classdef countdownGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RedNumberEditField         matlab.ui.control.NumericEditField
        RedNumberEditFieldLabel    matlab.ui.control.Label
        RedOperatorEditField       matlab.ui.control.EditField
        RedOperatorEditFieldLabel  matlab.ui.control.Label
        TimeLeftEditField          matlab.ui.control.EditField
        TimeLeftEditFieldLabel     matlab.ui.control.Label
        RedTargetEditField         matlab.ui.control.NumericEditField
        RedTargetEditFieldLabel    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        redBall % Description
        mouthOpen
        grass
        wall
        division
        gameover
        startImage
        startImage2
        gameMatrix
        N
        rx
        ry
        myTimer
        remainingTime
        gameOverFig
        startScreenFig
        square
        squareDirection
        sx
        sy
        difficulty
        soundEffect1
        soundEffect2
    end
    
    methods (Access = private)
        
        function setUpWorld(app)
            [y, Fs] = audioread('countdownSound.mp3');
            %sound(y,Fs)
            [app.soundEffect1, app.soundEffect2] = audioread('eating.mp3');
            app.N=16;
            %{
             app.gameMatrix = {'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W';
                              'W', 'E', '/', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', '-', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', '*', 'E', 'E', 'E', 'E', '+', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', '28', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'W', 'E', '/', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'W', 'E', '/', 'E', '4', '*', 'E', 'E', 'E', 'E','34', 'E', 'E', 'W';
                              'W', 'E', 'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'W', 'E', 'E', 'E', 'E', 'E', '32','14','E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'W', '14', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', '+', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', 'E', 'E', '*', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'E', 'W';
                              'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W'};
            %}

            
            app.gameMatrix = {'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W';
                              'W', 'E', 'E', 'W', 'E', 'E', 'E', '10', 'E', '/', 'E', '100', 'E', 'E', 'E', 'W';
                              'W', 'E', '+', 'W', 'E', '+', 'E', 'W', 'W', 'W', 'W', '*', '18', 'E', 'E', 'W';
                              'W', 'E', 'E', '-', 'E', 'W', '11', 'W', 'E', 'E', 'E', 'E', '*', 'W', 'E', 'W';
                              'W', '13', 'W', 'W', '-', 'W', 'E', 'W', 'E', 'W', 'W', 'E', 'W', 'E', 'E', 'W';
                              'W', 'E', 'W', 'W', 'E', 'W', 'E', 'E', 'E', 'W', '/', 'E', 'E', 'E', '-', 'W';
                              'W', '/', 'E', 'E', 'E', 'W', 'E', 'W', 'E', 'W', 'W', '2', 'W', 'E', '/', 'W';
                              'W', 'W', 'W', 'W', 'W', '23', 'E', 'W', 'E', 'W', 'W', 'E', 'W', 'E', 'E', 'W';
                              'W', 'E', 'E', '/', 'E', 'E', 'E', 'E', '*', 'E', 'E', 'E', 'W', 'E', '19', 'W';
                              'W', 'E', '-', 'W', 'W', 'E', 'W', 'W', 'E', 'W', 'W', 'W', 'W', '+', 'E', 'W';
                              'W', '50', 'W', 'E', '5', 'E', 'E', 'E', 'E', 'E', 'E', '54', 'E', 'E', 'E', 'W';
                              'W', 'E', 'W', 'W', 'W', 'W', 'E', 'W', 'W', 'W', 'W', 'E', 'W', 'W', 'E', 'W';
                              'W', 'E', 'E', '4', 'E', '65', '*', 'E', 'E', '38', 'E', '43', 'E', 'E', 'E', 'W';
                              'W', 'E', '-', 'W', 'W', 'W', 'W', 'E', '14', 'E', 'W', '*', 'W', 'W', 'E', 'W';
                              'W', '45', 'E', 'E', 'E', 'E', '11', 'E', 'W', 'E', 'E', 'E', 'E', '*', 'E', 'W';
                              'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W', 'W'};

            app.rx = 3; %3 from left to right
            app.ry = 4; %4th from top to bottom
            app.RedOperatorEditField.Value = 'N/A';
            app.RedNumberEditField.Value = randi([50, 200]);
            app.sx = 9;
            app.sy = 9;
            app.squareDirection = 'north';
        end
        
        function draw(app)
            cla(app.UIAxes); % clear the axis
            hold(app.UIAxes,'on');
            axis(app.UIAxes, [10 app.N*10+10 10 app.N*10+10]);
            set(app.UIAxes,'Visible','on');
            
            image(app.UIAxes, [0, app.N*10], [0, app.N*10], app.grass);

            image(app.UIAxes,[1+app.rx*10 9+app.rx*10],[1+app.ry*10 9+app.ry*10],app.redBall);
            image(app.UIAxes,[1+app.sx*10 9+app.sx*10],[1+app.sy*10 9+app.sy*10],app.square);

            for i = 1:app.N % from 1 to N (row number so y)
                for j =1:app.N % from 1 to N (collumn number so x)
                    theChar = app.gameMatrix{i,j};
                    if theChar == 'W' % we have a wall here
                        rectangle(app.UIAxes, 'Position', [j*10 i*10 10 10],'FaceColor',[0.5 0.5 0.5]); % walls are of size 10 by 10
                        %image(app.UIAxes, [j*10, j*10 + 10], [i*10, i*10 + 10], app.wall);
                        %walls are at position x*10 y*10
                    elseif theChar == 'E' % we should have nothing
                    elseif any([theChar == '/', theChar == '*', theChar == '-', theChar == '+'])  %we have an operator symbol
                        rectangle(app.UIAxes, 'Position', [j*10+2 i*10+2 6 6],'FaceColor',[0 1 1]);
                        text(app.UIAxes, j*10+5, i*10+5, theChar, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontWeight','bold','Color',[0 0 0]);
                    else % we have a number here
                        number = theChar;
                        rectangle(app.UIAxes, 'Position', [j*10+2 i*10+2 6 6],'FaceColor',[1 0.3 1]);
                        text(app.UIAxes, j*10+5, i*10+5, number, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontWeight','bold');
                    end
                end
            end
            
            if(app.gameMatrix{ app.ry, app.rx} == 'E')
                image(app.UIAxes,[1+app.rx*10 9+app.rx*10],[1+app.ry*10 9+app.ry*10],app.redBall);
            end
            if(app.gameMatrix{ app.ry, app.rx} ~= 'E') %We have just consumed a number/operator
                image(app.UIAxes,[1+app.rx*10 9+app.rx*10],[1+app.ry*10 9+app.ry*10],app.mouthOpen);
            end
        end
        
        function setNewTarget(app)
            randomInteger = randi([0, 50]);
            randomNumber = randomInteger * 10;
            app.RedTargetEditField.Value = randomNumber;    
        end

        function status = CheckSquare(app, x, y)
            % returns -1 if off limits
            % returns: 0 if clear, 1 if block, 2 if food
    
    
            % check within limits to prevent errors e.g. A(0,0) would give an
            % error
            if(x<1 || y<1 || x > app.N || y > app.N)
                status = -1;
                return;
            end
    
            status = app.gameMatrix{y,x};
        end
        
        function setTimer(app, startingTime)
            % Create a timer to update the countdown timer every second
            app.TimeLeftEditField.Value = sprintf('%d', startingTime);
            app.remainingTime = startingTime;
            app.myTimer = timer('TimerFcn', @(~,~)update(app), ...
                        'StartDelay', 0, ...
                        'Period', 1, ...
                        'ExecutionMode', 'fixedRate');
            start(app.myTimer);
            
            
        end

        % Function to update the countdown timer
        function update(app)
            % Update remaining time
            
            app.remainingTime = app.remainingTime - 1;
        
            % Update the Label or Text component with the remaining time in seconds
            app.TimeLeftEditField.Value = sprintf('%d', app.remainingTime);
        
            % Check if the countdown timer has reached zero
            if app.remainingTime == 0
                stop(app.myTimer); % Stop the timer when countdown reaches zero
                gameOver(app);
            end
            updateSquare(app);
            draw(app);
            disp('updating per sec')
        end
    
        function updateSquare(app)
            current = [app.sx app.sy];
            if app.squareDirection == "north"
                next = current + [0 1];
            elseif app.squareDirection == "east"
                next = current + [1 0];
            elseif app.squareDirection == "south"
                next = current - [0 1];
            elseif app.squareDirection == "west"
                next = current - [1 0];
            end

            status = CheckSquare(app, next(1), next(2)); %Here we check if the next square we want to go to is free
            if(status ~= 'W') %if we do not hit a wall square should go that direction
                app.sx = next(1);
                app.sy = next(2);
                app.gameMatrix{app.sy,app.sx}='E';
                % no change to next direction
            else % random direction
                if rand > 0.75
                    nextDirection = "east"; % right
                    next = current + [1 0];
                    
                elseif rand > 0.5
                    nextDirection = "west"; % left
                    next = current - [1 0];
                elseif rand > 0.25
                    nextDirection = "north"; %  up
                    next = current + [0 1];
                else
                    nextDirection = "south"; % down
                    next = current - [0 1];
                end
                status = CheckSquare(app, next(1), next(2)); %Here we check if the next square we want to go to is free
                if(status ~= 'W') %if we do not hit a wall square should go that direction
                    app.sx = next(1);
                    app.sy = next(2);
                    app.squareDirection = nextDirection;
                    app.gameMatrix{app.sy,app.sx}='E';
                end
                %If the random direction is not a wall then we go that
                %direction
            end

        end

        function result = isOperator(~,check)
            if any([check=='/', check=='*',check=='-', check=='+'])
                result = true;
            else 
                result = false;
            end
        end
        function result = isLetterOrOperator(~,check)
            if any([check=='/', check=='*',check=='-', check=='+'])
                result = true;
            elseif isletter(check)
                result = true;
            else 
                result = false;
            end
        end

        function gameOver(app)
            % Create a new UI figure for the GameOver Page
            app.gameOverFig = uifigure('Name', 'Game Over', 'Position', [100, 100, 1000, 900]);
            gameOverPanel = uipanel(app.gameOverFig, 'Position', [50, 50, 900, 800],'BackgroundColor','white');
            % Add a message indicating game over

            % Add the image
            gameOverImage = uiimage(gameOverPanel);
            gameOverImage.ImageSource = app.gameover; 
            gameOverImage.Position = [100, 300, 700, 600]; % Adjusted position and size
            
            points = abs(app.RedTargetEditField.Value - app.RedNumberEditField.Value);
            text = ['You were only ', num2str(points) ,' away from the target!'];
            uilabel(gameOverPanel, 'Text', text, 'FontWeight', 'bold', ...
                'FontSize', 45, 'Position', [275, 50, 500, 350], 'FontColor','blue','WordWrap','on');
            
            % Add a restart button
            restartButton = uibutton(gameOverPanel, 'Text', 'Restart',...
                'Position', [300, 50, 300, 100], 'ButtonPushedFcn', @(btn,event)restart(app));
            
            % Ensure the figure is displayed
            movegui(app.gameOverFig, 'center');
            app.gameOverFig.Visible = 'on';
        end

        function restart(app)
            close(app.gameOverFig);
            startupFcn(app);
        end

        function createStartScreen(app)
            % Create a new UI figure for the start screen
            app.startScreenFig = uifigure('Name', 'Start Screen', 'Position', [0, 0, 640, 480]);
            % Create a panel for other UI components
            startPanel = uipanel(app.startScreenFig, 'Position', [0, 0, 640, 480],'BackgroundColor','white');
            % Add instructions, level selection, and start button (similar to before)
            
            % Add the image
            app.startImage = imread('startImage.jpg');
            app.startImage2 = imread('startImage.png');
            gameOverImage = uiimage(startPanel);
            gameOverImage.ImageSource = app.startImage; 
            gameOverImage.Position = [0, 0, 640,480]; % Adjusted position and size
            instructions = {'Welcome to Count Down Explorer!', ...
                            'Use the W,A,S and D to navigate through the maze.', ...
                            'The goal is to get your number as close to the target as possible, before the timer runs out!', ...
                            'To do this first collect an operator, which you can see on the right.', ...
                            'Once you have collected an operator you can then collect a number to form an operation', ...
                            'to change your number. Try to also avoid the Black square as he will be eating your', ...
                            'numbers and operators. ', ...
                            'Good luck and have fun!'};
            uilabel(startPanel, 'Text', instructions, 'FontWeight', 'bold', ...
                    'FontSize', 14, 'Position', [60, 180, 500, 200], 'FontColor','white','WordWrap','on');
        
            levelLabel = uilabel(startPanel, 'Text', 'Select a level:', ...
                                'FontWeight', 'bold', 'FontSize', 14, ...
                                'Position', [120, 100, 150, 30], 'FontColor', 'white');
            levelDropdown = uidropdown(startPanel, 'Items', {'Easy', 'Medium', 'Hard'}, ...
                                        'Position', [280, 100, 150, 30]);

            timeLabel = uilabel(startPanel, 'Text', 'Select game time:', ...
                    'FontWeight', 'bold', 'FontSize', 14, ...
                    'Position', [120, 60, 250, 30], 'FontColor', 'white');

            time = uidropdown(startPanel, 'Items', {'60', '90', '120', '300'}, ...
                                      'Position', [280, 60, 100, 30]); 

            startButton = uibutton(startPanel, 'Text', 'Start',...
                                    'Position', [330, 10, 100, 40], ...
                                    'ButtonPushedFcn', @(btn,event)startGame(app, levelDropdown.Value, time.Value));
            movegui(app.startScreenFig, 'center');
            app.startScreenFig.Visible = 'on';
        end

        function startGame(app, levelSelected, time)
            app.difficulty=levelSelected;
            close(app.startScreenFig)
            app.redBall = flipud(imread('redBall.png'));
            app.mouthOpen = flipud(imread('mouthOpen.png'));
            app.grass = flipud(imread('grass.jpg'));
            app.wall = imread('wall.jpg');
            app.gameover = imread('gameover.png');
            app.division = imread('division.png');
            app.square = flipud(imread('square.png'));
            axis(app.UIAxes,[0 200 0 200]);
            setUpWorld(app);
            app.setTimer(str2num(time));
            app.draw();
            app.setNewTarget();
            app.squareDirection = 'north';
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.createStartScreen();

        
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
             if isequal(key,'d')
                check = CheckSquare(app, app.rx+1,app.ry);
                condition1 = check ~= 'W'; % Not hitting a wall
                condition2 = ~(isOperator(app, check) && (app.RedOperatorEditField.Value ~= "N/A")); % Not (hitting an operator while already having one)
                condition3 = ~(~isLetterOrOperator(app,check) && (app.RedOperatorEditField.Value == "N/A")); % Not (hitting a number without an operator)
                
                if all([condition1, condition2, condition3])
                    %if check was a number we have now moved into and
                    %consumed it
                    app.rx = app.rx+1; 
                    draw(app);
                    app.gameMatrix{app.ry,app.rx}='E';
                    
                    if any([check=='/' ,check=='*' ,check=='+' ,check=='-'])
                        app.RedOperatorEditField.Value = check;   
                        sound(app.soundEffect1,app.soundEffect2); % make a sound
                    end
                    if ~isLetterOrOperator(app,check)
                        operator = app.RedOperatorEditField.Value;

                        num2str(app.RedNumberEditField.Value)
                        round(eval([num2str(app.RedNumberEditField.Value), operator, [check]]))
                        sound(app.soundEffect1,app.soundEffect2); % make a sound
                        app.RedNumberEditField.Value = round(eval([num2str(app.RedNumberEditField.Value), operator, check]));
                        app.RedOperatorEditField.Value = 'N/A';
                    end
                end


             elseif isequal(key,'a')
                check = CheckSquare(app, app.rx-1,app.ry);
                condition1 = check ~= 'W'; % Not hitting a wall
                condition2 = ~(isOperator(app, check) && (app.RedOperatorEditField.Value ~= "N/A")); % Not (hitting an operator while already having one)
                condition3 = ~(~isLetterOrOperator(app,check) && (app.RedOperatorEditField.Value == "N/A")); % Not (hitting a number without an operator)
                
                if all([condition1, condition2, condition3])
                    app.rx = app.rx-1;
                    draw(app);
                    app.gameMatrix{app.ry,app.rx}='E';
                    if any([check=='/' ,check=='*' ,check=='+' ,check=='-'])
                        app.RedOperatorEditField.Value = check;  
                        sound(app.soundEffect1,app.soundEffect2);
                    end
                    if ~isLetterOrOperator(app,check)
                        operator = app.RedOperatorEditField.Value;
                        num2str(app.RedNumberEditField.Value)
                        round(eval([num2str(app.RedNumberEditField.Value), operator, [check]]))
                        sound(app.soundEffect1,app.soundEffect2);
                        app.RedNumberEditField.Value = round(eval([num2str(app.RedNumberEditField.Value), operator, check]));
                        app.RedOperatorEditField.Value = 'N/A';
                    end
                end


            elseif isequal(key,'s')
                check = CheckSquare(app, app.rx,app.ry-1);
                condition1 = check ~= 'W'; % Not hitting a wall
                condition2 = ~(isOperator(app, check) && (app.RedOperatorEditField.Value ~= "N/A"));
                % Not (hitting an operator while already having one)
                condition3 = ~(~isLetterOrOperator(app,check) && (app.RedOperatorEditField.Value == "N/A"));
                % Not (hitting a number without an operator)
                if all([condition1, condition2, condition3]) %all conditions are met
                    app.ry = app.ry-1; %move red down
                    draw(app);
                    app.gameMatrix{app.ry,app.rx}='E'; %consume any consumables
                    if any([check=='/' ,check=='*' ,check=='+' ,check=='-']) %if we eat a operator than 
                        app.RedOperatorEditField.Value = check; %put that operator in our operator box  
                        sound(app.soundEffect1,app.soundEffect2); % make a sound
                    end
                    if ~isLetterOrOperator(app,check) %if we eat a number
                        operator = app.RedOperatorEditField.Value; 
                        num2str(app.RedNumberEditField.Value)
                        round(eval([num2str(app.RedNumberEditField.Value), operator, [check] ]))
                        %we perform an evaluation of our current number and
                        %the new number with our operator.
                        app.RedNumberEditField.Value = round(eval([num2str(app.RedNumberEditField.Value), operator, check])); 
                        %update current number
                        app.RedOperatorEditField.Value = 'N/A'; %Make operator empty
                        sound(app.soundEffect1,app.soundEffect2); % make a sound
                    end
                end

             elseif isequal(key,'w')
                check = CheckSquare(app, app.rx,app.ry+1);
                condition1 = check ~= 'W'; % Not hitting a wall
                condition2 = ~(isOperator(app, check) && (app.RedOperatorEditField.Value ~= "N/A")); % Not (hitting an operator while already having one)
                condition3 = ~(~isLetterOrOperator(app,check) && (app.RedOperatorEditField.Value == "N/A")); % Not (hitting a number without an operator)
                
                if all([condition1, condition2, condition3])
                    app.ry = app.ry+1;
                    draw(app);
                    app.gameMatrix{app.ry,app.rx}='E';

                    if any([check=='/' ,check=='*' ,check=='+' ,check=='-'])
                        app.RedOperatorEditField.Value = check;     
                        sound(app.soundEffect1,app.soundEffect2); % make a sound
                    end
                    if ~isLetterOrOperator(app,check)
                        operator = app.RedOperatorEditField.Value;
                        num2str(app.RedNumberEditField.Value)
                        round(eval([num2str(app.RedNumberEditField.Value), operator, [check]]))
                        sound(app.soundEffect1,app.soundEffect2); % make a sound
                        app.RedNumberEditField.Value = round(eval([num2str(app.RedNumberEditField.Value), operator, check]));
                        app.RedOperatorEditField.Value = 'N/A';
                    end
                end
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.UIFigure)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Count Down Explorer')
            app.RedTargetEditFieldLabel = uilabel(app.UIFigure);
            app.RedTargetEditFieldLabel.Text = 'Red Target';
            app.RedTargetEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedTargetEditField.Value = 500;
            app.TimeLeftEditFieldLabel = uilabel(app.UIFigure);
            app.TimeLeftEditFieldLabel.Text = 'Time Left';
            app.TimeLeftEditField = uieditfield(app.UIFigure, 'text');
            app.TimeLeftEditField.Value = '40';
            app.RedOperatorEditFieldLabel = uilabel(app.UIFigure);
            app.RedOperatorEditFieldLabel.Text = 'Red Operator';
            app.RedOperatorEditField = uieditfield(app.UIFigure, 'text');
            app.RedNumberEditFieldLabel = uilabel(app.UIFigure);
            app.RedNumberEditFieldLabel.Text = 'Red Number';
            app.RedNumberEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = countdownGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end